classdef Lab_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberofsamplesSpinner       matlab.ui.control.Spinner
        NumberofsamplesSpinnerLabel  matlab.ui.control.Label
        ModulationIndexSpinner       matlab.ui.control.Spinner
        ModulationIndexSpinnerLabel  matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        FrequencySlider_2            matlab.ui.control.Slider
        FrequencySlider_2Label       matlab.ui.control.Label
        AmplitudeSliderLabel_2       matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Parameters
            m = app.ModulationIndexSpinner.Value;
            
            % Modulating signal generation 
            Fs = app.NumberofsamplesSpinner.Value;    % Number of samples
            Ts = 1/Fs;                            % Time period of modulating signal
            t = 0:Ts:0.5;                           % Total time for simulation
            Am = app.AmplitudeSlider.Value;       % Amplitude of modulating signal
            fm = app.FrequencySlider.Value;       % Frequency of modulating signal
            y_m = Am*sin(2*pi*fm*t);              % Eqation of modulating signal
            
            % Carrier signal generation 
            Ac = Am/m;                            % Amplitude of carrier signal 
            fc = app.FrequencySlider_2.Value;     % Frequency of carrier signal
            Tc = 1/fc;                            % Time period of carrier signal
            y_c= Ac*sin(2*pi*fc*t);               % Eqation of carrier signal

            % Modulated signal
            y = Ac*(1+m*sin(2*pi*fm*t)).*sin(2*pi*fc*t); % Equation of Amplitude
            
            % Plot
            plot(app.UIAxes, t, y_m)
            plot(app.UIAxes_4, t, y_c)
            plot(app.UIAxes_3, t, y)
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: FrequencySlider_2
        function FrequencySlider_2ValueChanged(app, event)
            value = app.FrequencySlider_2.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Modulated Signal')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Carrier Signal')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Amplitude')
            zlabel(app.UIAxes_4, 'Z')
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [10 100];
            app.FrequencySlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [];
            app.FrequencySlider.Value = 10;
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [1 10];
            app.AmplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [];
            app.AmplitudeSlider.Value = 1;
            app.AmplitudeSliderLabel_2 = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel_2.Text = 'Amplitude Modulator';
            app.FrequencySlider_2Label = uilabel(app.UIFigure);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.UIFigure);
            app.FrequencySlider_2.Limits = [200 1000];
            app.FrequencySlider_2.MajorTicks = [200 300 400 500 600 700 800 900 1000];
            app.FrequencySlider_2.ValueChangedFcn = createCallbackFcn(app, @FrequencySlider_2ValueChanged, true);
            app.FrequencySlider_2.MinorTicks = [];
            app.FrequencySlider_2.Value = 200;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ModulationIndexSpinnerLabel = uilabel(app.UIFigure);
            app.ModulationIndexSpinnerLabel.Text = 'Modulation Index';
            app.ModulationIndexSpinner = uispinner(app.UIFigure);
            app.ModulationIndexSpinner.Step = 0.1;
            app.ModulationIndexSpinner.Limits = [0.1 1];
            app.ModulationIndexSpinner.Value = 0.1;
            app.NumberofsamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofsamplesSpinnerLabel.Text = 'Number of samples';
            app.NumberofsamplesSpinner = uispinner(app.UIFigure);
            app.NumberofsamplesSpinner.Step = 50;
            app.NumberofsamplesSpinner.Limits = [100 1000];
            app.NumberofsamplesSpinner.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end