classdef DAW_user_interface_JH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ReverbPanel               matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        ApplyReverb               matlab.ui.control.Button
        FadeInPanel               matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        ApplyFadeIn               matlab.ui.control.Button
        sLabel_2                  matlab.ui.control.Label
        sLabel                    matlab.ui.control.Label
        StopTimeSpinnerLabel      matlab.ui.control.Label
        StopTimeSpinner           matlab.ui.control.Spinner
        StartTimeSpinnerLabel     matlab.ui.control.Label
        StartTimeSpinner          matlab.ui.control.Spinner
        FadeTypeDropDownLabel     matlab.ui.control.Label
        FadeTypeDropDown          matlab.ui.control.DropDown
        GridLayout10              matlab.ui.container.GridLayout
        PlayButton                matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        RedoButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        SelectedEffectsPanel      matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        GridLayout7               matlab.ui.container.GridLayout
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        FadeOutPanel_3            matlab.ui.container.Panel
        GridLayout2_4             matlab.ui.container.GridLayout
        ApplyFadeOut              matlab.ui.control.Button
        sLabel_7                  matlab.ui.control.Label
        sLabel_6                  matlab.ui.control.Label
        StopTimeSpinner_4Label    matlab.ui.control.Label
        StopTimeSpinner_4         matlab.ui.control.Spinner
        StartTimeSpinner_4Label   matlab.ui.control.Label
        StartTimeSpinner_4        matlab.ui.control.Spinner
        FadeTypeDropDown_4Label   matlab.ui.control.Label
        FadeTypeDropDown_4        matlab.ui.control.DropDown
        BrowseButton              matlab.ui.control.Button
        RhythmicAutoPanningPanel  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        LFORateHzKnob             matlab.ui.control.Knob
        LFORateHzKnobLabel        matlab.ui.control.Label
        ApplyPanning              matlab.ui.control.Button
        PanValueKnob              matlab.ui.control.Knob
        PanValueKnobLabel         matlab.ui.control.Label
        LFODepthKnob              matlab.ui.control.Knob
        LFODepthKnobLabel         matlab.ui.control.Label
        TremoloPanel              matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ApplyTremolo              matlab.ui.control.Button
        RateHzKnob                matlab.ui.control.Knob
        RateHzKnobLabel           matlab.ui.control.Label
        DepthKnob                 matlab.ui.control.Knob
        DepthLabel                matlab.ui.control.Label
        LFOTypeDropDown           matlab.ui.control.DropDown
        LFOTypeDropDownLabel      matlab.ui.control.Label
        EchoPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        ApplyEcho                 matlab.ui.control.Button
        sLabel_5                  matlab.ui.control.Label
        DelaySpinnerLabel         matlab.ui.control.Label
        DelaySpinner              matlab.ui.control.Spinner
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
%         signal = []
        signal
        index = 1
        time
        N
        Fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, path] = uigetfile('*.wav');
            app.FileNameEditField.Value = string(filename);
%             info = audioinfo(filename);
            [y,sample_freq] = audioread(filename);
            signal_length = length(y);
            Ts = 1/sample_freq;
            t = [0:signal_length-1]*Ts; t = t(:);
            plot(app.UIAxes,t,y)
%             app.signal(app.index,:) = y;
            app.signal = y;
            app.time = t;
            app.N = signal_length;
            app.Fs = sample_freq;
        end

        % Button pushed function: ApplyTremolo
        function ApplyTremoloButtonPushed(app, event)
%             carrier = app.signal(app.index,:);
%             app.index = app.index + 1;
            
            % Tremolo Parameters
            depth = app.DepthKnob.Value;
            rate = app.RateHzKnob.Value;
            amp = 0.5*(depth/100);
            offset = 1 - amp;
            
            % Synthesize modulation signal
            f = rate; % speed of effect
            phi = 0;
            if strcmp(app.LFOTypeDropDown.Value, 'Sine') == 1
                sw = sin(2*pi*f.*app.time+phi);
                type = 'Sine';
            elseif strcmp(app.LFOTypeDropDown.Value, 'Triangle') == 1
                sw = sawtooth(2*pi*f.*app.time+phi,0.5);
                type = 'Triangle';
            elseif strcmp(app.LFOTypeDropDown.Value, 'Sawtooth') == 1
                sw = sawtooth(2*pi*f.*app.time+phi);
                type = 'Sawtooth';
            else
                sw = square(2*pi*f.*app.time,50);
                type = 'Sqaure';
            end
            mod = amp.*sw + offset;
%             output = carrier.*mod;
            output = app.signal.*mod;
%             plot(app.UIAxes2,app.time(1:app.N),sw(1:app.N));  % To check
%             if correct signal wave form is generated
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
%             app.signal(app.index,:) = output;
            app.signal = output;
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Tremolo';
            app.TextArea.Value{end+1} = append('LFO Type: ', type);
            app.TextArea.Value{end+1} = append('Depth: ', num2str(depth), '%');
            app.TextArea.Value{end+1} = append('Rate: ', num2str(rate), 'Hz');
            app.TextArea.Value{end+1} = ' ';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%             sound(app.signal(app.index,:),app.Fs);
            sound(app.signal,app.Fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('Processed_signal.wav',app.signal,app.Fs);
        end

        % Button pushed function: ApplyPanning
        function ApplyPanningButtonPushed(app, event)
            % -100: Panning completely to left
            %  100: Panning completely to right
            %    0: Pan to centre
            
            % Synthesise the LFO modulator
            f = app.LFORateHzKnob.Value;
            depth = app.LFODepthKnob.Value;
            amp = 0.5*(depth/100); % Transform depth to amplitude of LFO
            offset = 1 - amp;
            lfo = amp*sin(2*pi*f*app.time) + offset;
            panValue = app.PanValueKnob.Value * lfo;
            panTransform = (panValue/200) + 0.5;
            leftAmp = sin((1-panTransform) * (pi/2));
            rightAmp = sin(panTransform * (pi/2));
            leftChannel = leftAmp.*app.signal;
            rightChannel = rightAmp.*app.signal;
            stereoSig = [leftChannel, rightChannel];
            
            % Select which channel to be plotted based on the pan value.
            % More significant change to the signal is seen in
            % 1. Right channel when panned to left
            % 2. Left channel when panned to right
            % Plot right channel if panValue < 0
            % Plot left channel if panValue > 0
            if app.PanValueKnob.Value > 0
                plot(app.UIAxes2,app.time,leftChannel);
                app.signal = leftChannel;
            elseif app.PanValueKnob.Value < 0
                plot(app.UIAxes2,app.time,rightChannel);
                app.signal = rightChannel;
            elseif app.PanValueKnob == 0
                plot(app.UIAxes2,app.time,leftChannel);
            end
            
            % Play sound
            sound(stereoSig,app.Fs);
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Rhythmic Auto Panning';
            app.TextArea.Value{end+1} = append('Pan Value: ', num2str(app.PanValueKnob.Value));
            app.TextArea.Value{end+1} = append('LFO Depth: ', num2str(depth), '%');
            app.TextArea.Value{end+1} = append('LFO Rate: ', num2str(f), 'Hz');
            app.TextArea.Value{end+1} = ' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FileNameEditFieldLabel = uilabel(app.GridLayout);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout, 'text');
            app.EchoPanel = uipanel(app.GridLayout);
            app.EchoPanel.Title = 'Echo';
            app.GridLayout3 = uigridlayout(app.EchoPanel);
            app.DelaySpinner = uispinner(app.GridLayout3);
            app.DelaySpinnerLabel = uilabel(app.GridLayout3);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.sLabel_5 = uilabel(app.GridLayout3);
            app.sLabel_5.Text = 's';
            app.ApplyEcho = uibutton(app.GridLayout3, 'push');
            app.ApplyEcho.Text = 'Apply';
            app.TremoloPanel = uipanel(app.GridLayout);
            app.TremoloPanel.Title = 'Tremolo';
            app.GridLayout4 = uigridlayout(app.TremoloPanel);
            app.LFOTypeDropDownLabel = uilabel(app.GridLayout4);
            app.LFOTypeDropDownLabel.Text = 'LFO Type';
            app.LFOTypeDropDown = uidropdown(app.GridLayout4);
            app.LFOTypeDropDown.Items = {'Sine', 'Square', 'Triangle', 'Sawtooth'};
            app.LFOTypeDropDown.Value = 'Sine';
            app.DepthLabel = uilabel(app.GridLayout4);
            app.DepthLabel.Text = 'Depth (%)';
            app.DepthKnob = uiknob(app.GridLayout4, 'continuous');
            app.RateHzKnobLabel = uilabel(app.GridLayout4);
            app.RateHzKnobLabel.Text = 'Rate (Hz)';
            app.RateHzKnob = uiknob(app.GridLayout4, 'continuous');
            app.RateHzKnob.Limits = [0.1 20];
            app.RateHzKnob.Value = 0.1;
            app.ApplyTremolo = uibutton(app.GridLayout4, 'push');
            app.ApplyTremolo.ButtonPushedFcn = createCallbackFcn(app, @ApplyTremoloButtonPushed, true);
            app.ApplyTremolo.Text = 'Apply';
            app.RhythmicAutoPanningPanel = uipanel(app.GridLayout);
            app.RhythmicAutoPanningPanel.Title = 'Rhythmic Auto Panning';
            app.GridLayout5 = uigridlayout(app.RhythmicAutoPanningPanel);
            app.LFODepthKnobLabel = uilabel(app.GridLayout5);
            app.LFODepthKnobLabel.Text = 'LFO Depth (%)';
            app.LFODepthKnob = uiknob(app.GridLayout5, 'continuous');
            app.PanValueKnobLabel = uilabel(app.GridLayout5);
            app.PanValueKnobLabel.Text = 'Pan Value';
            app.PanValueKnob = uiknob(app.GridLayout5, 'continuous');
            app.PanValueKnob.Limits = [-100 100];
            app.ApplyPanning = uibutton(app.GridLayout5, 'push');
            app.ApplyPanning.ButtonPushedFcn = createCallbackFcn(app, @ApplyPanningButtonPushed, true);
            app.ApplyPanning.Text = 'Apply';
            app.LFORateHzKnobLabel = uilabel(app.GridLayout5);
            app.LFORateHzKnobLabel.Text = 'LFO Rate (Hz)';
            app.LFORateHzKnob = uiknob(app.GridLayout5, 'continuous');
            app.LFORateHzKnob.Limits = [0.1 20];
            app.LFORateHzKnob.Value = 0.1;
            app.BrowseButton = uibutton(app.GridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FadeOutPanel_3 = uipanel(app.GridLayout);
            app.FadeOutPanel_3.Title = 'Fade Out';
            app.GridLayout2_4 = uigridlayout(app.FadeOutPanel_3);
            app.FadeTypeDropDown_4 = uidropdown(app.GridLayout2_4);
            app.FadeTypeDropDown_4.Items = {'Linear', 'Exponential'};
            app.FadeTypeDropDown_4.Value = 'Linear';
            app.FadeTypeDropDown_4Label = uilabel(app.GridLayout2_4);
            app.FadeTypeDropDown_4Label.Text = 'Fade Type';
            app.StartTimeSpinner_4 = uispinner(app.GridLayout2_4);
            app.StartTimeSpinner_4Label = uilabel(app.GridLayout2_4);
            app.StartTimeSpinner_4Label.Text = 'Start Time';
            app.StopTimeSpinner_4 = uispinner(app.GridLayout2_4);
            app.StopTimeSpinner_4Label = uilabel(app.GridLayout2_4);
            app.StopTimeSpinner_4Label.Text = 'Stop Time';
            app.sLabel_6 = uilabel(app.GridLayout2_4);
            app.sLabel_6.Text = 's';
            app.sLabel_7 = uilabel(app.GridLayout2_4);
            app.sLabel_7.Text = 's';
            app.ApplyFadeOut = uibutton(app.GridLayout2_4, 'push');
            app.ApplyFadeOut.Text = 'Apply';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Original Audio Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Audio Signal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2, 'Output Signal (Processed)')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Audio Signal')
            zlabel(app.UIAxes2, 'Z')
            app.SelectedEffectsPanel = uipanel(app.GridLayout);
            app.SelectedEffectsPanel.Title = 'Selected Effects';
            app.GridLayout9 = uigridlayout(app.SelectedEffectsPanel);
            app.TextArea = uitextarea(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout10, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RedoButton = uibutton(app.GridLayout10, 'push');
            app.RedoButton.Text = 'Redo';
            app.UndoButton = uibutton(app.GridLayout10, 'push');
            app.UndoButton.Text = 'Undo';
            app.PlayButton = uibutton(app.GridLayout10, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FadeInPanel = uipanel(app.GridLayout);
            app.FadeInPanel.Title = 'Fade In';
            app.GridLayout11 = uigridlayout(app.FadeInPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout11);
            app.FadeTypeDropDown = uidropdown(app.GridLayout2);
            app.FadeTypeDropDown.Items = {'Linear', 'Exponential', ''};
            app.FadeTypeDropDown.Value = 'Linear';
            app.FadeTypeDropDownLabel = uilabel(app.GridLayout2);
            app.FadeTypeDropDownLabel.Text = 'Fade Type';
            app.StartTimeSpinner = uispinner(app.GridLayout2);
            app.StartTimeSpinnerLabel = uilabel(app.GridLayout2);
            app.StartTimeSpinnerLabel.Text = 'Start Time';
            app.StopTimeSpinner = uispinner(app.GridLayout2);
            app.StopTimeSpinnerLabel = uilabel(app.GridLayout2);
            app.StopTimeSpinnerLabel.Text = 'Stop Time';
            app.sLabel = uilabel(app.GridLayout2);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.GridLayout2);
            app.sLabel_2.Text = 's';
            app.ApplyFadeIn = uibutton(app.GridLayout2, 'push');
            app.ApplyFadeIn.Text = 'Apply';
            app.ReverbPanel = uipanel(app.GridLayout);
            app.ReverbPanel.Title = 'Reverb';
            app.GridLayout8 = uigridlayout(app.ReverbPanel);
            app.ApplyReverb = uibutton(app.GridLayout8, 'push');
            app.ApplyReverb.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAW_user_interface_JH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end