classdef GroupB2_project01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ReverbPanel               matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        ApplyReverb               matlab.ui.control.Button
        FadeInPanel               matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        ApplyFadeIn               matlab.ui.control.Button
        sLabel_2                  matlab.ui.control.Label
        sLabel                    matlab.ui.control.Label
        StopTimeSpinnerLabel      matlab.ui.control.Label
        FadeInStopTimeSpinner     matlab.ui.control.Spinner
        StartTimeSpinnerLabel     matlab.ui.control.Label
        FadeInStartTimeSpinner    matlab.ui.control.Spinner
        FadeTypeDropDownLabel     matlab.ui.control.Label
        FadeInTypeDropDown        matlab.ui.control.DropDown
        GridLayout10              matlab.ui.container.GridLayout
        PlayButton                matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        RedoButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        SelectedEffectsPanel      matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        GridLayout7               matlab.ui.container.GridLayout
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        FadeOutPanel_3            matlab.ui.container.Panel
        GridLayout2_4             matlab.ui.container.GridLayout
        ApplyFadeOut              matlab.ui.control.Button
        sLabel_7                  matlab.ui.control.Label
        sLabel_6                  matlab.ui.control.Label
        StopTimeSpinner_4Label    matlab.ui.control.Label
        FadeOutStopTimeSpinner    matlab.ui.control.Spinner
        StartTimeSpinner_4Label   matlab.ui.control.Label
        FadeOutStartTimeSpinner   matlab.ui.control.Spinner
        FadeTypeDropDown_4Label   matlab.ui.control.Label
        FadeOutTypeDropDown       matlab.ui.control.DropDown
        BrowseButton              matlab.ui.control.Button
        RhythmicAutoPanningPanel  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        LFORateHzKnob             matlab.ui.control.Knob
        LFORateHzKnobLabel        matlab.ui.control.Label
        ApplyPanning              matlab.ui.control.Button
        AmplitudeKnob             matlab.ui.control.Knob
        AmplitudeKnobLabel        matlab.ui.control.Label
        TremoloPanel              matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ApplyTremolo              matlab.ui.control.Button
        RateHzKnob                matlab.ui.control.Knob
        RateHzKnobLabel           matlab.ui.control.Label
        DepthKnob                 matlab.ui.control.Knob
        DepthLabel                matlab.ui.control.Label
        LFOTypeDropDown           matlab.ui.control.DropDown
        LFOTypeDropDownLabel      matlab.ui.control.Label
        EchoPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        ApplyEcho                 matlab.ui.control.Button
        sLabel_5                  matlab.ui.control.Label
        DelaySpinnerLabel         matlab.ui.control.Label
        DelaySpinner              matlab.ui.control.Spinner
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        signal              % Original signal
        updated_signal      % Output signal
        index = 1           % Index
        time                % Time vector
        N                   % Signal length
        Fs                  % Sampling frequency
        Ts                  % Sampling period
        
        % Buffers tracking the state of applied effects
        current = [];       % Current state of effects buffer
        past = [];          % Past state of effects buffer
        future = [];        % Future state of effects buffer
        
        % Buffers tracking the state of effects parameters
        current_param = [];
        past_param = [];
        future_param = [];
    end
    
    methods (Access = private)
        
        function output = FadeIn(app, input, param)
            % Update UI
            app.FadeInTypeDropDown.Value = param.type();
            app.FadeInStartTimeSpinner.Value = param.num(1);
            app.FadeInStopTimeSpinner.Value = param.num(2);
            
            % Gather input parameters
            type = param.type;
            start = param.num(1);
            stop = param.num(2);
            
            % Calculate number of samples
            numSamples = (stop - start)/app.Ts + 1;
            % Calculate fade in amplitude
            a = linspace(0, 1, numSamples); a = a(:);
            
            ones_fill = ones(1, app.N - length(a)); ones_fill = ones_fill(:);
            
            if type == "Linear"
                % Match amplitude vector size with input array size
                  fadeIn = [a; ones_fill];
                output = fadeIn.*input;
            elseif type == "Exponential"
                fadeIn = [a.^2; ones_fill];
                output = fadeIn.*input;                
            end
            % Plot ouput
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'FadeIn';
            app.TextArea.Value{end+1} = append('Type: ', param.type);
            app.TextArea.Value{end+1} = append('Start time: ', num2str(start), 's');
            app.TextArea.Value{end+1} = append('Stop time: ', num2str(stop), 's');
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = FadeOut(app, input, param)
            % Gather input parameters
            type = param.type;
            start = param.num(1);
            stop = param.num(2);
            
            % Calculate number of samples
            numSamples = (stop - start)/app.Ts + 1;
            % Calculate fade in amplitude
            a = linspace(0, 1, numSamples); a = a(:);
            
            zeros_fill = zeros(app.N - length(a), 1);
            
            if type == "Linear"
                % Match amplitude vector size with input array size
                fadeOut = [1-a; zeros_fill];
                output = fadeOut.*input;
            elseif type == "Exponential"
                fadeOut = [1-a.^2; zeros_fill];
                output = fadeOut.*input;                
            end
            
            % Plot ouput
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'FadeOut';
            app.TextArea.Value{end+1} = append('Type: ', param.type);
            app.TextArea.Value{end+1} = append('Start time: ', num2str(start), 's');
            app.TextArea.Value{end+1} = append('Stop time: ', num2str(stop), 's');
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = Echo(app, input, param)
            % Convert time delay to unit samples
            % Amplitude of delay, reduces amplitude by 3/4
            a = -0.75;
            
            % Initialize output array
            y = zeros(app.N,1);
            
            % Convert time delay to units of samples
            d = fix(param.num*app.Fs);
            
            for n = 1:app.N
                % Avoid indexing negative sample number
                if n < d+1
                    % Ouput = input
                    y(n,1) = input(n, 1);
                % Compute delayed ouput
                else
                    % Ouput = current input + deyalyed output
                    y(n, 1) = input(n,1) + (-a)*y(n-d,1);        
                end
            end
            output = y;
            
            % Plot ouput
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Feedback Echo';
            app.TextArea.Value{end+1} = append('Delay time: ', num2str(param.num), 's');
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = Reverb(app, input, param)
            % Two channel impulse response
            [h] = audioread('reverbIR.wav');     % Stereo IR
            
            % Convolve impulse response with input signal
            yLeft = conv(input, h(:,1));
            yRight = conv(input, h(:,2));
            
            output = [yLeft, yRight];
            
            % Plot ouput
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Reverb';
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = Tremolo(app, input, param)
            % Update UI
            app.LFOTypeDropDown.Value = param.type;
            app.DepthKnob.Value = param.num(1);
            app.RateHzKnob.Value = param.num(2);
            
            % Tremolo Parameters
            depth = app.DepthKnob.Value;
            rate = app.RateHzKnob.Value;
            amp = 0.5*(depth/100);
            offset = 1 - amp;
            
            % Synthesize modulation signal
            f = rate; % speed of effect
            phi = 0;
            if strcmp(app.LFOTypeDropDown.Value, 'Sine') == 1
                sw = sin(2*pi*f.*app.time+phi);
                type = 'Sine';
            elseif strcmp(app.LFOTypeDropDown.Value, 'Triangle') == 1
                sw = sawtooth(2*pi*f.*app.time+phi,0.5);
                type = 'Triangle';
            elseif strcmp(app.LFOTypeDropDown.Value, 'Sawtooth') == 1
                sw = sawtooth(2*pi*f.*app.time+phi);
                type = 'Sawtooth';
            else
                sw = square(2*pi*f.*app.time,50);
                type = 'Sqaure';
            end
            mod = amp.*sw + offset;
%             output = carrier.*mod;
            output = input.*mod;
%             plot(app.UIAxes2,app.time(1:app.N),sw(1:app.N));  % To check
%             if correct signal wave form is generated
            plot(app.UIAxes2,app.time(1:app.N),output(1:app.N));
%             app.signal(app.index,:) = output;
%             app.signal = output;
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Tremolo';
            app.TextArea.Value{end+1} = append('LFO Type: ', type);
            app.TextArea.Value{end+1} = append('Depth: ', num2str(depth), '%');
            app.TextArea.Value{end+1} = append('Rate: ', num2str(rate), 'Hz');
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = RhythmicAutoPanning(app, input, param)
            % Update UI
            app.AmplitudeKnob.Value = param.num(1);
            app.LFORateHzKnob.Value = param.num(2);
            
            % -100: Panning completely to left
            %  100: Panning completely to right
            %    0: Pan to centre
            
            stereoSig = [];
            
            % Synthesise the LFO modulator
            f = app.LFORateHzKnob.Value;
            amp = app.AmplitudeKnob.Value;
            panValue = amp*sin(2*pi*f*app.time);
            panTransform = (panValue/200) + 0.5;
            leftAmp = sin((1-panTransform) * (pi/2));
            rightAmp = sin(panTransform * (pi/2));
            leftChannel = leftAmp.*input;
            rightChannel = rightAmp.*input;
            stereoSig = [leftChannel, rightChannel];
            
            % Select which channel to be plotted based on the pan value.
            % More significant change to the signal is seen in
            % 1. Right channel when panned to left
            % 2. Left channel when panned to right
            % Plot right channel if panValue < 0
            % Plot left channel if panValue > 0
            if app.AmplitudeKnob.Value > 0
                plot(app.UIAxes2,app.time,leftChannel);
                output = leftChannel;
            elseif app.AmplitudeKnob.Value < 0
                plot(app.UIAxes2,app.time,rightChannel);
                output = rightChannel;
            elseif app.AmplitudeKnob == 0
                plot(app.UIAxes2,app.time,leftChannel);
            end
            
%             app.signal = stereoSig;
            output = stereoSig;
            
            % Write to Text Editfield
            app.TextArea.Value{end+1} = 'Rhythmic Auto Panning';
            app.TextArea.Value{end+1} = append('Amplitude: ', num2str(app.AmplitudeKnob.Value));
            app.TextArea.Value{end+1} = append('LFO Rate: ', num2str(f), 'Hz');
            app.TextArea.Value{end+1} = ' ';
        end
        
        function output = UpdateSignal(app)
            % Clear text area
            app.TextArea.Value = '';
            input = app.signal;
            % Intilize output vector;
            output = [];
            i = 1;
            for effect = app.current
                if effect == "FadeIn"
                    output = FadeIn(app, input, app.current_param(i));
                elseif effect == "FadeOut"
                    output = FadeOut(app, input, app.current_param(i));
                elseif effect == "Echo"
                    output = Echo(app, input, app.current_param(i));
                elseif effect == "Reverb"
                    output = Reverb(app, input, app.current_param(i));
                elseif effect == "Panning"
                    output = RhythmicAutoPanning(app, input, app.current_param(i));
                elseif effect == "Tremolo"
                    output = Tremolo(app, input, app.current_param(i));
                end
                i = i + 1;
                input = output;
            end
            
            if isempty(output)
                % Plot ouput
                plot(app.UIAxes2,app.time(1:app.N),app.signal(1:app.N));
            else
                app.updated_signal = output;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Browse computer files
            [filename, path] = uigetfile('*.wav');
            
            % Display file path
            app.FileNameEditField.Value = string([path, filename]);
            
            % Read audio file
            [y,sample_freq] = audioread(filename);
            
            % Compute signal length, sampling period, and time vector
            signal_length = length(y);
            Ts = 1/sample_freq;
            t = [0:signal_length-1]*Ts; t = t(:);
            
            % Plot original signal
            plot(app.UIAxes,t,y)

            % Assign values to class properties
            app.signal = y;
            app.time = t;
            app.N = signal_length;
            app.Fs = sample_freq;
            app.Ts = Ts;
            app.updated_signal = [];
            
            % Set FadeIn/FadeOut default start and stop time
            app.FadeInStartTimeSpinner.Value = 0;
            app.FadeInStopTimeSpinner.Value = (app.N-1)*Ts;
            app.FadeOutStartTimeSpinner.Value = 0;
            app.FadeOutStopTimeSpinner.Value = (app.N-1)*Ts;
            
            % Clear state buffers
            app.current = [];
            app.current_param = [];
            app.past = [];
            app.past_param = [];
            app.future = [];
            app.future_param = [];
            
        end

        % Button pushed function: ApplyTremolo
        function ApplyTremoloButtonPushed(app, event)
            % Grab input parameters
            param.type = app.LFOTypeDropDown.Value;
            depth = app.DepthKnob.Value;
            rate = app.RateHzKnob.Value;
            param.num = [depth, rate];
            
            % Track parameter changes
            app.past_param = app.current_param;            
            app.current_param = [app.current_param, param];

            % Track changes
            app.past = app.current;
            app.current = [app.current, "Tremolo"];

            UpdateSignal(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%             sound(app.signal(app.index,:),app.Fs);
            sound(app.updated_signal,app.Fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('Processed_signal.wav',app.updated_signal,app.Fs);
        end

        % Button pushed function: ApplyPanning
        function ApplyPanningButtonPushed(app, event)
            % Grab input values
            param.type = "None";
            amplitude = app.AmplitudeKnob.Value;
            frequency = app.LFORateHzKnob.Value;
            param.num = [amplitude, frequency];
        
            % Track parameter changes
            app.past_param = app.current_param;
            app.current_param = [app.current_param, param];

            % Track changes
            app.past = app.current;
            app.current = [app.current, "Panning"];

            UpdateSignal(app);
        end

        % Button pushed function: ApplyFadeIn
        function ApplyFadeInButtonPushed(app, event)
            % Grab input values
            param.type = app.FadeInTypeDropDown.Value;
            start = app.FadeInStartTimeSpinner.Value;
            stop = app.FadeInStopTimeSpinner.Value;
            param.num = [start, stop];
            
            % Track parameter changes
            app.past_param = app.current_param;
            app.current_param = [app.current_param, param];
            
            % Track effects changes
            app.past = app.current;
            app.current = [app.current, "FadeIn"];
            
            UpdateSignal(app);
        end

        % Button pushed function: ApplyFadeOut
        function ApplyFadeOutButtonPushed(app, event)
            % Grab input values
            param.type = app.FadeOutTypeDropDown.Value;
            start = app.FadeOutStartTimeSpinner.Value;
            stop = app.FadeOutStopTimeSpinner.Value;
            param.num = [start, stop];
            
            % Track parameter changes
            app.past_param = app.current_param;
            app.current_param = [app.current_param, param];
            
            % Track changes
            app.past = app.current;
            app.current = [app.current, "FadeOut"];
            
            UpdateSignal(app);           
        end

        % Button pushed function: ApplyEcho
        function ApplyEchoButtonPushed(app, event)
            % Grab input values
            param.type = "None";
            delay_time = app.DelaySpinner.Value();
            param.num = [delay_time];
            
            % Track parameter changes
            app.past_param = app.current_param;
            app.current_param = [app.current_param, param];
            
            % Track changes
            app.past = app.current;
            app.current = [app.current, "Echo"];

            UpdateSignal(app);         
        end

        % Button pushed function: ApplyReverb
        function ApplyReverbButtonPushed(app, event)
            % Define dummy struct for keeping data structure consistent
            param.type = "None";
            param.num = [0];
           
            % Track parameter changes
            app.past_param = app.current_param;
            app.current_param = [app.current_param, param];
            
            % Track changes
            app.past = app.current;
            app.current = [app.current, "Reverb"];
            
            UpdateSignal(app);          
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            % Store removed effect as a future state
            app.future = [app.future, app.current(end)];
            app.future_param = [app.future_param, app.current_param(end)];
            % Update current state with past state
            app.current = app.past;
            app.current_param = app.past_param;
            % Pop last element from past array
            if not(isempty(app.past))
                app.past(end) = [];
                app.past_param(end) = [];
            end
            UpdateSignal(app);
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            % Store current state in past buffer
            app.past = app.current;
            app.past_param = app.current_param;
            % Update current state with future state
            app.current = [app.current, app.future(end)];
            app.current_param = [app.current_param, app.future_param(end)];
            % Pop last element from future array
            if not(isempty(app.future))
                app.future(end) = [];
                app.future_param(end) = [];
            end
            UpdateSignal(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FileNameEditFieldLabel = uilabel(app.GridLayout);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout, 'text');
            app.EchoPanel = uipanel(app.GridLayout);
            app.EchoPanel.Title = 'Echo';
            app.GridLayout3 = uigridlayout(app.EchoPanel);
            app.DelaySpinner = uispinner(app.GridLayout3);
            app.DelaySpinner.Step = 0.01;
            app.DelaySpinner.Limits = [0 1];
            app.DelaySpinnerLabel = uilabel(app.GridLayout3);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.sLabel_5 = uilabel(app.GridLayout3);
            app.sLabel_5.Text = 's';
            app.ApplyEcho = uibutton(app.GridLayout3, 'push');
            app.ApplyEcho.ButtonPushedFcn = createCallbackFcn(app, @ApplyEchoButtonPushed, true);
            app.ApplyEcho.Text = 'Apply';
            app.TremoloPanel = uipanel(app.GridLayout);
            app.TremoloPanel.Title = 'Tremolo';
            app.GridLayout4 = uigridlayout(app.TremoloPanel);
            app.LFOTypeDropDownLabel = uilabel(app.GridLayout4);
            app.LFOTypeDropDownLabel.Text = 'LFO Type';
            app.LFOTypeDropDown = uidropdown(app.GridLayout4);
            app.LFOTypeDropDown.Items = {'Sine', 'Square', 'Triangle', 'Sawtooth'};
            app.LFOTypeDropDown.Value = 'Sine';
            app.DepthLabel = uilabel(app.GridLayout4);
            app.DepthLabel.Text = 'Depth (%)';
            app.DepthKnob = uiknob(app.GridLayout4, 'continuous');
            app.RateHzKnobLabel = uilabel(app.GridLayout4);
            app.RateHzKnobLabel.Text = 'Rate (Hz)';
            app.RateHzKnob = uiknob(app.GridLayout4, 'continuous');
            app.RateHzKnob.Limits = [0.1 20];
            app.RateHzKnob.Value = 0.1;
            app.ApplyTremolo = uibutton(app.GridLayout4, 'push');
            app.ApplyTremolo.ButtonPushedFcn = createCallbackFcn(app, @ApplyTremoloButtonPushed, true);
            app.ApplyTremolo.Text = 'Apply';
            app.RhythmicAutoPanningPanel = uipanel(app.GridLayout);
            app.RhythmicAutoPanningPanel.Title = 'Rhythmic Auto Panning';
            app.GridLayout5 = uigridlayout(app.RhythmicAutoPanningPanel);
            app.AmplitudeKnobLabel = uilabel(app.GridLayout5);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.GridLayout5, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.ApplyPanning = uibutton(app.GridLayout5, 'push');
            app.ApplyPanning.ButtonPushedFcn = createCallbackFcn(app, @ApplyPanningButtonPushed, true);
            app.ApplyPanning.Text = 'Apply';
            app.LFORateHzKnobLabel = uilabel(app.GridLayout5);
            app.LFORateHzKnobLabel.Text = 'LFO Rate (Hz)';
            app.LFORateHzKnob = uiknob(app.GridLayout5, 'continuous');
            app.LFORateHzKnob.Limits = [0.1 20];
            app.LFORateHzKnob.Value = 0.1;
            app.BrowseButton = uibutton(app.GridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FadeOutPanel_3 = uipanel(app.GridLayout);
            app.FadeOutPanel_3.Title = 'Fade Out';
            app.GridLayout2_4 = uigridlayout(app.FadeOutPanel_3);
            app.FadeOutTypeDropDown = uidropdown(app.GridLayout2_4);
            app.FadeOutTypeDropDown.Items = {'Linear', 'Exponential'};
            app.FadeOutTypeDropDown.Value = 'Linear';
            app.FadeTypeDropDown_4Label = uilabel(app.GridLayout2_4);
            app.FadeTypeDropDown_4Label.Text = 'Fade Type';
            app.FadeOutStartTimeSpinner = uispinner(app.GridLayout2_4);
            app.FadeOutStartTimeSpinner.Step = 0.1;
            app.FadeOutStartTimeSpinner.Limits = [0 100];
            app.StartTimeSpinner_4Label = uilabel(app.GridLayout2_4);
            app.StartTimeSpinner_4Label.Text = 'Start Time';
            app.FadeOutStopTimeSpinner = uispinner(app.GridLayout2_4);
            app.StopTimeSpinner_4Label = uilabel(app.GridLayout2_4);
            app.StopTimeSpinner_4Label.Text = 'Stop Time';
            app.sLabel_6 = uilabel(app.GridLayout2_4);
            app.sLabel_6.Text = 's';
            app.sLabel_7 = uilabel(app.GridLayout2_4);
            app.sLabel_7.Text = 's';
            app.ApplyFadeOut = uibutton(app.GridLayout2_4, 'push');
            app.ApplyFadeOut.ButtonPushedFcn = createCallbackFcn(app, @ApplyFadeOutButtonPushed, true);
            app.ApplyFadeOut.Text = 'Apply';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Original Audio Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Audio Signal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2, 'Output Signal (Processed)')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Audio Signal')
            zlabel(app.UIAxes2, 'Z')
            app.SelectedEffectsPanel = uipanel(app.GridLayout);
            app.SelectedEffectsPanel.Title = 'Selected Effects';
            app.GridLayout9 = uigridlayout(app.SelectedEffectsPanel);
            app.TextArea = uitextarea(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout10, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RedoButton = uibutton(app.GridLayout10, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.UndoButton = uibutton(app.GridLayout10, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.PlayButton = uibutton(app.GridLayout10, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FadeInPanel = uipanel(app.GridLayout);
            app.FadeInPanel.Title = 'Fade In';
            app.GridLayout11 = uigridlayout(app.FadeInPanel);
            app.GridLayout2 = uigridlayout(app.GridLayout11);
            app.FadeInTypeDropDown = uidropdown(app.GridLayout2);
            app.FadeInTypeDropDown.Items = {'Linear', 'Exponential'};
            app.FadeInTypeDropDown.Value = 'Linear';
            app.FadeTypeDropDownLabel = uilabel(app.GridLayout2);
            app.FadeTypeDropDownLabel.Text = 'Fade Type';
            app.FadeInStartTimeSpinner = uispinner(app.GridLayout2);
            app.FadeInStartTimeSpinner.Step = 0.1;
            app.FadeInStartTimeSpinner.Limits = [0 100];
            app.StartTimeSpinnerLabel = uilabel(app.GridLayout2);
            app.StartTimeSpinnerLabel.Text = 'Start Time';
            app.FadeInStopTimeSpinner = uispinner(app.GridLayout2);
            app.FadeInStopTimeSpinner.Step = 0.1;
            app.FadeInStopTimeSpinner.Limits = [0 100];
            app.StopTimeSpinnerLabel = uilabel(app.GridLayout2);
            app.StopTimeSpinnerLabel.Text = 'Stop Time';
            app.sLabel = uilabel(app.GridLayout2);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.GridLayout2);
            app.sLabel_2.Text = 's';
            app.ApplyFadeIn = uibutton(app.GridLayout2, 'push');
            app.ApplyFadeIn.ButtonPushedFcn = createCallbackFcn(app, @ApplyFadeInButtonPushed, true);
            app.ApplyFadeIn.Text = 'Apply';
            app.ReverbPanel = uipanel(app.GridLayout);
            app.ReverbPanel.Title = 'Reverb';
            app.GridLayout8 = uigridlayout(app.ReverbPanel);
            app.ApplyReverb = uibutton(app.GridLayout8, 'push');
            app.ApplyReverb.ButtonPushedFcn = createCallbackFcn(app, @ApplyReverbButtonPushed, true);
            app.ApplyReverb.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupB2_project01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end